classdef NoiseCancellation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        DelayEditField              matlab.ui.control.NumericEditField
        DelayEditFieldLabel         matlab.ui.control.Label
        RLSCheckBox                 matlab.ui.control.CheckBox
        ResetButton                 matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        AddNoiseandMixButton        matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        NLMSCheckBox                matlab.ui.control.CheckBox
        LMSCheckBox                 matlab.ui.control.CheckBox
        LambdaEditField             matlab.ui.control.NumericEditField
        LambdaEditFieldLabel        matlab.ui.control.Label
        MuEditField                 matlab.ui.control.NumericEditField
        MuEditFieldLabel            matlab.ui.control.Label
        FilterLengthEditField       matlab.ui.control.NumericEditField
        FilterLengthEditFieldLabel  matlab.ui.control.Label
        PlayFilteredButton          matlab.ui.control.Button
        PlayNoisyButton             matlab.ui.control.Button
        PlayNoiseButton             matlab.ui.control.Button
        PlayCleanButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes1                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        clean
        noise
        noisy
        filtered
        Fs = 44100
        errorFigure
        coeffFigure

        player
        cursor
    end
    
    methods (Access = private)

        function [y,e,w_hist] = runLMS(app,x,d,order,mu)
        
            N = length(x);
            w = zeros(order,1);
            w_hist = zeros(order,N);
            y = zeros(N,1);
            e = zeros(N,1);
            
            for n = order:N
            u = x(n:-1:n-order+1);
            y(n) = w'*u;
            e(n) = d(n) - y(n);
            w = w + mu*u*e(n);
            w_hist(:,n) = w;
            end
        
        end
        
        function [y,e,w_hist] = runRLS(app,x,d,order,lambda)
    
            N = length(x);
            w = zeros(order,1);
            P = eye(order)*1000;
            
            w_hist = zeros(order,N);
            y = zeros(N,1);
            e = zeros(N,1);
            
            for n = order:N
            u = x(n:-1:n-order+1);
            y(n) = w'*u;
            e(n) = d(n) - y(n);
            k = (P*u)/(lambda + u'*P*u);
            w = w + k*e(n);
            P = (P - k*u'*P)/lambda;
            w_hist(:,n) = w;
            end
        
        end

        function [y,e,w_hist] = runNLMS(app,x,d,order,mu0,epsilon)
            N = length(x);
            w = zeros(order,1);
            w_hist = zeros(order,N);
            y = zeros(N,1);
            e = zeros(N,1);
            
            for n = order:N
                u = x(n:-1:n-order+1);
                y(n) = w'*u;
                e(n) = d(n) - y(n);
                norm_factor = u'*u + epsilon;
                mu_n = mu0 / norm_factor;
                w = w + mu_n * e(n) * u;
                w_hist(:,n) = w;
            end
        end

        
        function playSignal(app, signal, ax)

            if isempty(signal)
                return
            end
            
            if ~isempty(app.player)
                if strcmp(app.player.Running,'on')
                    stop(app.player)
                end
            end
            
            if ~isempty(app.cursor) && isvalid(app.cursor)
                delete(app.cursor)
            end
            
            app.cursor = xline(ax,1,'r');
            
            player = audioplayer(signal,app.Fs);
            app.player = player;
            
            set(player,'TimerPeriod',0.03,'TimerFcn',@(~,~) moveCursor());
            
            play(player)
            
                function moveCursor()
                    if isempty(app.cursor), return, end
                    app.cursor.Value = player.CurrentSample;
                    drawnow limitrate
                end
     
        end

        function x_shifted = shiftNoise(app, x, delay)
            
            N = length(x);
        
            n = (1:N)';
        
            x_shifted = interp1(n, x, n - delay, 'linear', 0);
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.clean = [];
            app.noise = [];
            app.noisy = [];
            app.filtered = [];
            
            cla(app.UIAxes1)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)

            if ~isempty(app.errorFigure)
                if isvalid(app.errorFigure)
                    close(app.errorFigure)
                end
            end
            
            if ~isempty(app.coeffFigure)
                if isvalid(app.coeffFigure)
                    close(app.coeffFigure)
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.noisy)
                uialert(app.UIFigure,'Add noise first','Error')
                return
            end
            
            order = app.FilterLengthEditField.Value;
            mu = app.MuEditField.Value;          % используется и для LMS, и как mu0 для NLMS
            lambda = app.LambdaEditField.Value;
            delay = app.DelayEditField.Value;
            
            x = app.shiftNoise(app.noise, delay);
            d = app.noisy;
            
            % Инициализация всех переменных
            e_lms = []; w_lms = [];
            e_rls = []; w_rls = [];
            e_nlms = []; w_nlms = [];
            
            if app.LMSCheckBox.Value
                [~,e_lms,w_lms] = app.runLMS(x,d,order,mu);
            end
            
            if app.RLSCheckBox.Value
                [~,e_rls,w_rls] = app.runRLS(x,d,order,lambda);
            end
            
            if app.NLMSCheckBox.Value
                epsilon = 1e-6;   % малая константа для защиты от деления на ноль
                [~,e_nlms,w_nlms] = app.runNLMS(x,d,order,mu,epsilon);
            end
            
            % Определяем отфильтрованный сигнал (приоритет: NLMS > LMS > RLS)
            if app.NLMSCheckBox.Value
                app.filtered = e_nlms;
            elseif app.LMSCheckBox.Value
                app.filtered = e_lms;
            elseif app.RLSCheckBox.Value
                app.filtered = e_rls;
            else
                uialert(app.UIFigure,'Select at least one algorithm','Error');
                return
            end
            
            plot(app.UIAxes4, app.filtered)
            title(app.UIAxes4,'Filtered Signal')
            
            % График сходимости ошибки
            if ~isempty(app.errorFigure) && isvalid(app.errorFigure)
                close(app.errorFigure)
            end
            app.errorFigure = figure;
            hold on
            legStr = {};
            if app.LMSCheckBox.Value
                plot(abs(e_lms(1:10:end)),'b')
                legStr{end+1} = 'LMS';
            end
            if app.RLSCheckBox.Value
                plot(abs(e_rls(1:10:end)),'r')
                legStr{end+1} = 'RLS';
            end
            if app.NLMSCheckBox.Value
                plot(abs(e_nlms(1:10:end)),'g')
                legStr{end+1} = 'NLMS';
            end
            title('Error Convergence')
            legend(legStr)
            
            % График коэффициентов фильтра
            if ~isempty(app.coeffFigure) && isvalid(app.coeffFigure)
                close(app.coeffFigure)
            end
            app.coeffFigure = figure;
            hold on
            if app.LMSCheckBox.Value
                plot(w_lms(:,1:10:end)')
            end
            if app.RLSCheckBox.Value
                plot(w_rls(:,1:10:end)')
            end
            if app.NLMSCheckBox.Value
                plot(w_nlms(:,1:10:end)')
            end
            title('Filter Coefficients')

        end

        % Button pushed function: AddNoiseandMixButton
        function AddNoiseandMixButtonPushed(app, event)
            if isempty(app.clean)
                uialert(app.UIFigure,'Load signal first','Error')
                return
            end
            
            N = length(app.clean);
            
            noise = randn(N,1);
            noise = noise/max(abs(noise));
            
            app.noise = noise;
            app.noisy = app.clean + noise;
            
            plot(app.UIAxes2, noise)
            title(app.UIAxes2,'Noise')
            
            plot(app.UIAxes3, app.noisy)
            title(app.UIAxes3,'Noisy Signal')

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            if file==0, return, end
            
            [x,Fs] = audioread(fullfile(path,file));
            
            app.clean = x(:,1);
            app.Fs = Fs;
            
            plot(app.UIAxes1, app.clean)
            title(app.UIAxes1,'Original Signal')
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            app.playSignal(app.filtered, app.UIAxes4)
        end

        % Button pushed function: PlayNoisyButton
        function PlayNoisyButtonPushed(app, event)
            app.playSignal(app.noisy, app.UIAxes3)
        end

        % Button pushed function: PlayNoiseButton
        function PlayNoiseButtonPushed(app, event)
            app.playSignal(app.noise, app.UIAxes2)
        end

        % Button pushed function: PlayCleanButton
        function PlayCleanButtonPushed(app, event)
            app.playSignal(app.clean, app.UIAxes1)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlayCleanButton = uibutton(app.LeftPanel, 'push');
            app.PlayCleanButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCleanButtonPushed, true);
            app.PlayCleanButton.Text = {'Play'; 'Clean'};
            app.PlayNoiseButton = uibutton(app.LeftPanel, 'push');
            app.PlayNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoiseButtonPushed, true);
            app.PlayNoiseButton.Text = {'Play'; 'Noise'};
            app.PlayNoisyButton = uibutton(app.LeftPanel, 'push');
            app.PlayNoisyButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoisyButtonPushed, true);
            app.PlayNoisyButton.Text = {'Play'; 'Noisy'};
            app.PlayFilteredButton = uibutton(app.LeftPanel, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = {'Play'; 'Filtered'};
            app.FilterLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.FilterLengthEditFieldLabel.Text = 'Filter Length ';
            app.FilterLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FilterLengthEditField.Value = 32;
            app.MuEditFieldLabel = uilabel(app.LeftPanel);
            app.MuEditFieldLabel.Text = 'Mu';
            app.MuEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MuEditField.Value = 0.01;
            app.LambdaEditFieldLabel = uilabel(app.LeftPanel);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LambdaEditField.Value = 0.99;
            app.LMSCheckBox = uicheckbox(app.LeftPanel);
            app.LMSCheckBox.Text = 'LMS';
            app.LMSCheckBox.Value = true;
            app.NLMSCheckBox = uicheckbox(app.LeftPanel);
            app.NLMSCheckBox.Text = 'NLMS';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddNoiseandMixButton = uibutton(app.LeftPanel, 'push');
            app.AddNoiseandMixButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseandMixButtonPushed, true);
            app.AddNoiseandMixButton.Text = 'Add Noise and Mix';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RLSCheckBox = uicheckbox(app.LeftPanel);
            app.RLSCheckBox.Text = 'RLS';
            app.DelayEditFieldLabel = uilabel(app.LeftPanel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, 'Clean')
            xlabel(app.UIAxes1, 'Sample Index')
            ylabel(app.UIAxes1, 'Amplitude')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Noise')
            xlabel(app.UIAxes2, 'Sample Index')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Noisy Signal')
            xlabel(app.UIAxes3, 'Sample Index')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Filtered Signal')
            xlabel(app.UIAxes4, 'Sample Index')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoiseCancellation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end